classdef Project_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Lamp_1            matlab.ui.control.Lamp
        Button_1          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        player1LampLabel  matlab.ui.control.Label
        player_1          matlab.ui.control.Lamp
        player2LampLabel  matlab.ui.control.Label
        player_2          matlab.ui.control.Lamp
        NewGameButton     matlab.ui.control.Button
        QUITButton        matlab.ui.control.Button
        Lamp_2            matlab.ui.control.Lamp
        Lamp_3            matlab.ui.control.Lamp
        Lamp_4            matlab.ui.control.Lamp
        Lamp_5            matlab.ui.control.Lamp
        Lamp_6            matlab.ui.control.Lamp
        Lamp_7            matlab.ui.control.Lamp
        Lamp_8            matlab.ui.control.Lamp
        Lamp_9            matlab.ui.control.Lamp
        Lamp_10           matlab.ui.control.Lamp
        Lamp_11           matlab.ui.control.Lamp
        Lamp_12           matlab.ui.control.Lamp
        Lamp_13           matlab.ui.control.Lamp
        Lamp_14           matlab.ui.control.Lamp
        Lamp_15           matlab.ui.control.Lamp
        Lamp_16           matlab.ui.control.Lamp
        Lamp_17           matlab.ui.control.Lamp
        Lamp_18           matlab.ui.control.Lamp
        Lamp_19           matlab.ui.control.Lamp
        Lamp_20           matlab.ui.control.Lamp
        Lamp_21           matlab.ui.control.Lamp
        Lamp_22           matlab.ui.control.Lamp
        Lamp_23           matlab.ui.control.Lamp
        Lamp_24           matlab.ui.control.Lamp
        Lamp_25           matlab.ui.control.Lamp
        Lamp_26           matlab.ui.control.Lamp
        Lamp_27           matlab.ui.control.Lamp
        Lamp_28           matlab.ui.control.Lamp
        Lamp_29           matlab.ui.control.Lamp
        Lamp_30           matlab.ui.control.Lamp
        Lamp_31           matlab.ui.control.Lamp
        Lamp_32           matlab.ui.control.Lamp
        Lamp_33           matlab.ui.control.Lamp
        Lamp_34           matlab.ui.control.Lamp
        Lamp_35           matlab.ui.control.Lamp
        Lamp_36           matlab.ui.control.Lamp
        Lamp_37           matlab.ui.control.Lamp
        Lamp_38           matlab.ui.control.Lamp
        Lamp_39           matlab.ui.control.Lamp
        Lamp_40           matlab.ui.control.Lamp
        Lamp_41           matlab.ui.control.Lamp
        Lamp_42           matlab.ui.control.Lamp
    end

    
    methods (Access = private)
        
        function Connect_4(app, button_pushed)
            global Led_name board FullR FullL HR HL clientID Left_Handle Right_Handle sim
            win = Check_Win(app, board);
            C_p1 = app.player_1.Color(2);
            C_p2 = app.player_2.Color(2);
            
            Led_col = button_pushed;
            Led_row = max(find(board(:,Led_col) == 0));
            if ~isempty(max(find(board(:,Led_col) == 0))) && ~win
                Lamp_val = (Led_col - 1)*6 + Led_row;
                if C_p1 == 0.8
                    board(Led_row, Led_col) = 2;
                    if C_p2 == 1
                        app.(Led_name{Lamp_val}).Color = [1 1 0]; % turns yellow
                        % move sim left arm
                        
                        [~,HL,~,~,~]=sim.simxReadProximitySensor(clientID,Left_Handle,sim.simx_opmode_streaming);
                        FullL = FullL-1;
                        if ~HL
                            SortChip(app);
                            LoadLRamp(app);
                        end
                        MoveLArm(app,Led_col);
                    else
                        app.(Led_name{Lamp_val}).Color = [1 0 0]; % turns red
                        % move sim right arm
                        
                        FullR = FullR-1;
                        [~,HR,~,~,~]=sim.simxReadProximitySensor(clientID,Right_Handle,sim.simx_opmode_streaming);
                        if ~HR
                            SortChip(app);
                            LoadRRamp(app);
                        end
                        MoveRArm(app,Led_col);
                    end
                else
                    board(Led_row, Led_col) = 1;
                    if C_p1 == 1
                        app.(Led_name{Lamp_val}).Color = [1 1 0]; % turns yellow
                        % move sim left arm
                        
                        [~,HL,~,~,~]=sim.simxReadProximitySensor(clientID,Left_Handle,sim.simx_opmode_streaming);
                        FullL = FullL-1;
                        if ~HL
                            SortChip(app);
                            LoadLRamp(app);
                        end
                        MoveLArm(app,Led_col);
                    else
                        app.(Led_name{Lamp_val}).Color = [1 0 0]; % turns red
                        % move sim right arm
                        
                        FullR = FullR-1;
                        
                        [~,HR,~,~,~]=sim.simxReadProximitySensor(clientID,Right_Handle,sim.simx_opmode_streaming);
                        if ~HR
                            SortChip(app);
                            LoadRRamp(app);
                        end
                        MoveRArm(app,Led_col);
                    end
                end
                
                %Change Player
                if C_p1 == 0.8
                    app.player_2.Color = [0.8 0.8 0.8];   %Turn Grey
                    if C_p2 == 1
                        app.player_1.Color = [1 0 0];          %Turn red
                    else
                        app.player_1.Color = [1 1 0];         %Turn yellow
                    end
                else
                    app.player_1.Color = [0.8 0.8 0.8];   %Turn Grey
                    if C_p1 == 1
                        app.player_2.Color = [1 0 0];          %Turn red
                    else
                        app.player_2.Color = [1 1 0];         %Turn yellow
                    end
                end
            end
            
        end
        
        function win = Check_Win(app, board) %Checks whether any player has won the game
            mpos = zeros([12,7]);
            mneg = zeros([12,7]);
            
            % i rows and j cols
            
            for i = 1:6
                for j=1:7
                    mpos(i+j-1,j)=board(i,j);
                    mneg(i-j+7,j)=board(i,j);
                end
            end
            
            pos_diag = mpos(4:9,:);
            neg_diag = mneg(4:9,:);
            
            for i = 1:7
                p1_row_win = diff(find(board(:,i) == 1));
                p2_row_win = diff(find(board(:,i) == 2));
                if(i < 7)
                    p1_col_win = diff(find(board(i,:) == 1));
                    p2_col_win = diff(find(board(i,:) == 2));
                    p1_pos_diag_win = diff(find(pos_diag(i,:) == 1));
                    p2_pos_diag_win = diff(find(pos_diag(i,:) == 2));
                    p1_neg_diag_win = diff(find(neg_diag(i,:) == 1));
                    p2_neg_diag_win = diff(find(neg_diag(i,:) == 2));
                end
                if(length(strfind(p1_row_win', [1 1 1])) > 0 || length(strfind(p1_pos_diag_win, [1 1 1]))> 0|| length(strfind(p1_neg_diag_win, [1 1 1]))> 0|| length(strfind(p1_col_win, [1 1 1]))> 0)
                    h = figure('units','pixels','position',[500 500 200 50],'windowstyle','modal');
                    uicontrol('style','text','string','Player 1 has Won!!!!!!!','units','pixels','position',[75 10 50 30]);
                    pause(5)
                    close(h);
                    win = 1;
                    break
                elseif(length(strfind(p2_row_win', [1 1 1])) > 0 || length(strfind(p2_pos_diag_win, [1 1 1]))> 0|| length(strfind(p2_neg_diag_win, [1 1 1]))> 0|| length(strfind(p2_col_win, [1 1 1]))> 0)
                    h = figure('units','pixels','position',[500 500 200 50],'windowstyle','modal');
                    uicontrol('style','text','string','Player 2 has Won!!!!!!!','units','pixels','position',[75 10 50 30]);
                    pause(5)
                    close(h);
                    win = 1;
                    break
                end
                win = 0;
            end
        end
        
        
        function MoveLArm(app,Led_col)
            %Led_col = ceil(Led_col);
            global sim clientID LS1 LS2 Lelbow Loriento Lwrist Lhand Lgripper Left_Handle
            left_S_1_ang = [-2.21,-2.14,-2.06,-1.97,-1.89,-1.8,-1.7172]; %Angles for LS1
            left_or_ang = -1*left_S_1_ang - pi/2; %Angles for Oriento
            left_elbow = [0.15,-0.05,-0.14,-0.24,-0.34,-0.65,-0.65]; %Distance of Elbow
            
            %Get the chip from the loader
            [~] = sim.simxSetJointTargetPosition(clientID,LS2,8.5*pi/180  ,sim.simx_opmode_blocking);
            pause(0.3);
            [~] = sim.simxSetJointTargetPosition(clientID,Lhand,-0.28,sim.simx_opmode_blocking);
            pause(0.3);
            
            %Collect the Chip
            [~,SEE,~,detectedObjectHandle,~] = sim.simxReadProximitySensor(clientID,Left_Handle,sim.simx_opmode_streaming);
            pause(0.1)
            [~,SEE,~,detectedObjectHandle,~] = sim.simxReadProximitySensor(clientID,Left_Handle,sim.simx_opmode_buffer);
            pause(0.1)
            [~]=sim.simxSetObjectParent(clientID,detectedObjectHandle,Lgripper,0,sim.simx_opmode_blocking);
            pause(1)
            [~] = sim.simxSetJointTargetPosition(clientID,LS2,14*pi/180  ,sim.simx_opmode_blocking);
            pause(0.5)
            
            %Deposit Chip into Connect 4 board
            [~] = sim.simxSetJointTargetPosition(clientID,LS1,left_S_1_ang(Led_col)  ,sim.simx_opmode_blocking);
            [~] = sim.simxSetJointTargetPosition(clientID,Lelbow,left_elbow(Led_col),sim.simx_opmode_blocking);
            [~] = sim.simxSetJointTargetPosition(clientID,Lwrist,-95*pi/180 ,sim.simx_opmode_blocking);
            [~] = sim.simxSetJointTargetPosition(clientID,Loriento,left_or_ang(Led_col) ,sim.simx_opmode_blocking);
            pause(0.2)
            [~] = sim.simxSetJointTargetPosition(clientID,LS2,5*pi/180  ,sim.simx_opmode_blocking); 
            
            %Drop chip
            pause(1);
            [~]=sim.simxSetObjectParent(clientID,detectedObjectHandle,-1,0,sim.simx_opmode_blocking);
            pause(1);
            Reset_Arm(app,1)
        end
        
        function MoveRArm(app,Led_col)
            global sim clientID RS1 RS2 Relbow Roriento Rwrist Rhand Rgripper Right_Handle
            Right_S_1_ang = [1.70,1.8,1.89,1.99,2.06,2.14,2.21]; %Angles for RS1
            Right_or_ang = -1*Right_S_1_ang + pi/2; %Angles for Oriento
            Right_elbow = [-0.65,-0.65,-0.45,-0.32,-0.2,-0.05,0.2]; %Distance of Elbow
            
            %Get the chip from the loader
            [~] = sim.simxSetJointTargetPosition(clientID,RS2,8.5*pi/180  ,sim.simx_opmode_blocking);
            pause(1);
            [~] = sim.simxSetJointTargetPosition(clientID,Rhand,-0.28,sim.simx_opmode_blocking);
            pause(1);
            
            %Collect the Chip
            [~,SEE,~,detectedObjectHandle,~] = sim.simxReadProximitySensor(clientID,Right_Handle,sim.simx_opmode_streaming);
            pause(0.1)
            [~,SEE,~,detectedObjectHandle,~] = sim.simxReadProximitySensor(clientID,Right_Handle,sim.simx_opmode_buffer);
            pause(0.1)
            [~]=sim.simxSetObjectParent(clientID,detectedObjectHandle,Rgripper,0,sim.simx_opmode_blocking);
            pause(1)
            [~] = sim.simxSetJointTargetPosition(clientID,RS2,14*pi/180  ,sim.simx_opmode_blocking);
            pause(0.5)
            
            %Deposit Chip into Connect 4 board
            [~] = sim.simxSetJointTargetPosition(clientID,RS1,Right_S_1_ang(Led_col),sim.simx_opmode_blocking);
            [~] = sim.simxSetJointTargetPosition(clientID,Relbow,Right_elbow(Led_col),sim.simx_opmode_blocking);
            [~] = sim.simxSetJointTargetPosition(clientID,Rwrist,-89*pi/180 ,sim.simx_opmode_blocking);
            [~] = sim.simxSetJointTargetPosition(clientID,Roriento,Right_or_ang(Led_col) ,sim.simx_opmode_blocking);
            pause(0.2);
            [~] = sim.simxSetJointTargetPosition(clientID,RS2,1*pi/180  ,sim.simx_opmode_blocking);
            
            %Drop Chip
            pause(1);
            [~]=sim.simxSetObjectParent(clientID,detectedObjectHandle,-1,0,sim.simx_opmode_blocking);
            pause(1);
            Reset_Arm(app,2)    
        end
        
        function LoadLRamp(app)
            global Loader_Door_PL Loader_Lift_PL Loader_Slide_PL Loader_B_SL Loader_T_SL Loader_Ramp_SL...
                DoorV SlideV LiftV BL TL RL LoadedL FullL clientID sim 
            %open loading box
            h = figure('units','pixels','position',[500 500 200 50],'windowstyle','modal');
            uicontrol('style','text','string','Loading...','units','pixels','position',[75 10 50 30]);
            
            [~,RL,~,~,~]=sim.simxReadProximitySensor(clientID,Loader_Ramp_SL,sim.simx_opmode_buffer);
             while ~RL
                %check sensors
                [~,BL,~,~,~]=sim.simxReadProximitySensor(clientID,Loader_B_SL,sim.simx_opmode_buffer);
                [~,TL,~,~,~]=sim.simxReadProximitySensor(clientID,Loader_T_SL,sim.simx_opmode_buffer);
                [~,RL,~,~,~]=sim.simxReadProximitySensor(clientID,Loader_Ramp_SL,sim.simx_opmode_buffer);
                
                BL = double(BL);
                TL = double(TL);
                RL = double(RL);
                
                LoaderState = bin2dec(num2str(BL*100+TL*10+RL));
                
                if LoaderState==0 %no chip in bottom or top and ramp empty
                    if LoadedL
                        sim.simxSetJointTargetVelocity(clientID,Loader_Door_PL,DoorV,sim.simx_opmode_blocking); %Closed
                    else
                        sim.simxSetJointTargetVelocity(clientID,Loader_Door_PL,-DoorV,sim.simx_opmode_blocking); %Open
                    end
                    %             sim.simxSetJointTargetVelocity(clientID,Loader_Slide_P,-SlideV,sim.simx_opmode_blocking); %Open
                    sim.simxSetJointTargetVelocity(clientID,Loader_Lift_PL,-LiftV,sim.simx_opmode_blocking); %Down
                    
                elseif LoaderState==2 % chip detected at top and ramp empty
                    LoadedL = false;
                    FullL = FullL+1;
                    sim.simxSetJointTargetVelocity(clientID,Loader_Door_PL,-DoorV,sim.simx_opmode_blocking); %Open
                    sim.simxSetJointTargetVelocity(clientID,Loader_Slide_PL,SlideV,sim.simx_opmode_blocking); %Closed
                    sim.simxSetJointTargetVelocity(clientID,Loader_Lift_PL,-LiftV,sim.simx_opmode_blocking); %Down
                    
                elseif LoaderState==4 % chip at bottom and ramp not full
                    LoadedL = true;
                    
                    sim.simxSetJointTargetVelocity(clientID,Loader_Door_PL,DoorV,sim.simx_opmode_blocking); %Closed
                    sim.simxSetJointTargetVelocity(clientID,Loader_Slide_PL,-SlideV,sim.simx_opmode_blocking); %Open
                    %             pause(0.05)
                    sim.simxSetJointTargetVelocity(clientID,Loader_Lift_PL,LiftV,sim.simx_opmode_blocking); %Up
                    
                else % ramp full or chip detected at bottom and top
                    sim.simxSetJointTargetVelocity(clientID,Loader_Door_PL,DoorV,sim.simx_opmode_blocking); %Closed
                    sim.simxSetJointTargetVelocity(clientID,Loader_Slide_PL,SlideV,sim.simx_opmode_blocking); %Closed
                    sim.simxSetJointTargetVelocity(clientID,Loader_Lift_PL,-LiftV,sim.simx_opmode_blocking); %Down
                end
                pause(0.01)
             end
             close(h);
        end
        
        function LoadRRamp(app) 
            global Loader_Door_P Loader_Lift_P Loader_Slide_P Loader_B_S Loader_T_S Loader_Ramp_S ...
                DoorV SlideV LiftV RR BR TR LoadedR FullR clientID sim
            %open loading box
            h = figure('units','pixels','position',[500 500 200 50],'windowstyle','modal');
            uicontrol('style','text','string','Loading...','units','pixels','position',[75 10 50 30]);
            
            [~,RR,~,~,~]=sim.simxReadProximitySensor(clientID,Loader_Ramp_S,sim.simx_opmode_buffer);
            while ~RR
                %check sensors
                [~,BR,~,~,~]=sim.simxReadProximitySensor(clientID,Loader_B_S,sim.simx_opmode_buffer);
                [~,TR,~,~,~]=sim.simxReadProximitySensor(clientID,Loader_T_S,sim.simx_opmode_buffer);
                [~,RR,~,~,~]=sim.simxReadProximitySensor(clientID,Loader_Ramp_S,sim.simx_opmode_buffer);
                
                BR = double(BR);
                TR = double(TR);
                RR = double(RR);
                
                LoaderState = bin2dec(num2str(BR*100+TR*10+RR));
                
                if LoaderState==0 %no chip in bottom or top and ramp empty
                    if LoadedR
                        sim.simxSetJointTargetVelocity(clientID,Loader_Door_P,DoorV,sim.simx_opmode_blocking); %Closed
                    else
                        sim.simxSetJointTargetVelocity(clientID,Loader_Door_P,-DoorV,sim.simx_opmode_blocking); %Open
                    end
                    %             sim.simxSetJointTargetVelocity(clientID,Loader_Slide_P,-SlideV,sim.simx_opmode_blocking); %Open
                    sim.simxSetJointTargetVelocity(clientID,Loader_Lift_P,-LiftV,sim.simx_opmode_blocking); %Down
                    
                elseif LoaderState==2 % chip detected at top and ramp empty
                    LoadedR = false;
                    FullR = FullR+1;
                    sim.simxSetJointTargetVelocity(clientID,Loader_Door_P,-DoorV,sim.simx_opmode_blocking); %Open
                    sim.simxSetJointTargetVelocity(clientID,Loader_Slide_P,SlideV,sim.simx_opmode_blocking); %Closed
                    sim.simxSetJointTargetVelocity(clientID,Loader_Lift_P,-LiftV,sim.simx_opmode_blocking); %Down
                    
                elseif LoaderState==4 % chip at bottom and ramp not full
                    LoadedR = true;
                    
                    sim.simxSetJointTargetVelocity(clientID,Loader_Door_P,DoorV,sim.simx_opmode_blocking); %Closed
                    sim.simxSetJointTargetVelocity(clientID,Loader_Slide_P,-SlideV,sim.simx_opmode_blocking); %Open
                    %             pause(0.05)
                    sim.simxSetJointTargetVelocity(clientID,Loader_Lift_P,LiftV,sim.simx_opmode_blocking); %Up
                    
                else % ramp full or chip detected at bottom and top
                    sim.simxSetJointTargetVelocity(clientID,Loader_Door_P,DoorV,sim.simx_opmode_blocking); %Closed
                    sim.simxSetJointTargetVelocity(clientID,Loader_Slide_P,SlideV,sim.simx_opmode_blocking); %Closed
                    sim.simxSetJointTargetVelocity(clientID,Loader_Lift_P,-LiftV,sim.simx_opmode_blocking); %Down
                end
                pause(0.01)
            end
            close(h);
        end
        
        function Reset_Arm(app, arm_number)
            global sim clientID LS1 LS2 Lelbow Loriento Lwrist Lhand RS1 RS2 Relbow Roriento Rwrist Rhand
            % number 1 = left
            % number 2 = right
            switch arm_number
                case 1
                    [~] = sim.simxSetJointTargetPosition(clientID,LS2,30*pi/180  ,sim.simx_opmode_blocking);
                    pause(0.2);
                    [~] = sim.simxSetJointTargetPosition(clientID,LS1,-85*pi/180  ,sim.simx_opmode_blocking);
                    [~] = sim.simxSetJointTargetPosition(clientID,Lelbow,-0.62,sim.simx_opmode_blocking);
                    [~] = sim.simxSetJointTargetPosition(clientID,Lwrist,-98*pi/180 ,sim.simx_opmode_blocking);
                    [~] = sim.simxSetJointTargetPosition(clientID,Loriento,85*pi/180 - pi/2 ,sim.simx_opmode_blocking);
                    pause(0.2);
                    [~] = sim.simxSetJointTargetPosition(clientID,LS2,12*pi/180  ,sim.simx_opmode_blocking);
                    [~] = sim.simxSetJointTargetPosition(clientID,Lhand,0,sim.simx_opmode_blocking);
                case 2
                    [~] = sim.simxSetJointTargetPosition(clientID,RS2,30*pi/180  ,sim.simx_opmode_blocking);
                    pause(0.2);
                    [~] = sim.simxSetJointTargetPosition(clientID,RS1,85*pi/180  ,sim.simx_opmode_blocking);
                    [~] = sim.simxSetJointTargetPosition(clientID,Relbow,-0.60,sim.simx_opmode_blocking);
                    [~] = sim.simxSetJointTargetPosition(clientID,Rwrist,-98.5*pi/180 ,sim.simx_opmode_blocking);
                    [~] = sim.simxSetJointTargetPosition(clientID,Roriento,-85*pi/180 + pi/2 ,sim.simx_opmode_blocking);
                    pause(0.2);
                    [~] = sim.simxSetJointTargetPosition(clientID,RS2,12*pi/180  ,sim.simx_opmode_blocking);
                    [~] = sim.simxSetJointTargetPosition(clientID,Rhand,0,sim.simx_opmode_blocking);
            end
            
        end
        
        function SortChip(app)
            global sim clientID Scammer Sorter Funnel_LockT Funnel_LockB Chip_Color_Check
            % need door logic
            % prox sensors
            
            sim.simxReadProximitySensor(clientID,Chip_Color_Check,sim.simx_opmode_streaming);
            pause(0.1);


            [~,~,~]=sim.simxGetVisionSensorImage2(clientID,Scammer,0,sim.simx_opmode_streaming); 
            
            % close top door
            sim.simxSetJointTargetVelocity(clientID, Funnel_LockT, 0.3, sim.simx_opmode_blocking);
            pause(5)
            % open bottom door
            sim.simxSetJointTargetPosition(clientID, Funnel_LockB, -.5, sim.simx_opmode_blocking);
            pause(1)
            % check if chip is there
            pause(0.1);
            [~,Sense,~,~,~] = sim.simxReadProximitySensor(clientID,Chip_Color_Check,sim.simx_opmode_buffer);
            
            % run sorter
            while Sense
                pause(0.1);
                [~,~,image]=sim.simxGetVisionSensorImage2(clientID,Scammer,0,sim.simx_opmode_buffer);
                
                image = double(image);
                
                pause(0.1)
                
                if image(:,:,1)==255 && image(:,:,2) == 0
                    pause(0.1)
                    sim.simxSetJointTargetPosition(clientID,Sorter,(30*pi)/180,sim.simx_opmode_blocking);
                    pause(3)
                else
                    pause(0.1)
                    sim.simxSetJointTargetPosition(clientID,Sorter,(-30*pi)/180,sim.simx_opmode_blocking);
                    pause(3)
                end
                pause(0.5)
                sim.simxSetJointTargetPosition(clientID,Sorter,0,sim.simx_opmode_blocking);
                pause(0.1);
                [~,Sense,~,~,~] = sim.simxReadProximitySensor(clientID,Chip_Color_Check,sim.simx_opmode_buffer);
                pause(0.1)
            end
            % close bottom door 
            sim.simxSetJointTargetPosition(clientID, Funnel_LockB, 0, sim.simx_opmode_blocking);
            % open top door
            sim.simxSetJointTargetVelocity(clientID, Funnel_LockT, -0.3, sim.simx_opmode_blocking);
            pause(3)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Start the Api
            global sim clientID RS1 RS2 Relbow Roriento Rwrist Rhand Rgripper ...
                LS1 LS2 Lelbow Loriento Lwrist Lhand Lgripper ...
                Loader_Door_P Loader_Lift_P Loader_Slide_P Loader_B_S Loader_T_S Loader_Ramp_S ...
                Loader_Door_PL Loader_Lift_PL Loader_Slide_PL Loader_B_SL Loader_T_SL Loader_Ramp_SL ...
                LiftV DoorV SlideV LoadedR LoadedL RR BR TR RL BL TL FullR FullL ...
                Right_Handle Left_Handle HR HL ...
                Sorter Scammer Funnel_LockT Funnel_LockB Chip_Color_Check ...
                Rack_OP Rack_OP0 Rack_OP1 Rack_OP2 Rack_OP3 Rack_OP4 Rack_OP5 Push1 ...
                Push2 Push3 Push4 Push5 Push6 Push7 Reset_Setup
            
            FullR = 0;
            FullL= 0;
            sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
            sim.simxFinish(-1); % just in case, close all opened connections
            
            clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
            
            % Sensors and joints handles
            % Right side getting intialized
            [~, RS1] = sim.simxGetObjectHandle(clientID,'RS1',sim.simx_opmode_blocking);
            [~, RS2] = sim.simxGetObjectHandle(clientID,'RS2',sim.simx_opmode_blocking);
            [~, Relbow] = sim.simxGetObjectHandle(clientID,'Relbow',sim.simx_opmode_blocking);
            [~, Roriento] = sim.simxGetObjectHandle(clientID,'Revolute_joint0',sim.simx_opmode_blocking);
            [~, Rwrist] = sim.simxGetObjectHandle(clientID,'Rwrist',sim.simx_opmode_blocking);
            [~, Rhand] = sim.simxGetObjectHandle(clientID,'Rhand',sim.simx_opmode_blocking);
            [~, Rgripper] = sim.simxGetObjectHandle(clientID,'Prismatic_joint0',sim.simx_opmode_blocking);
            
            
            % Left side getting intialized
            [~, LS1] = sim.simxGetObjectHandle(clientID,'LS1',sim.simx_opmode_blocking);
            [~, LS2] = sim.simxGetObjectHandle(clientID,'LS2',sim.simx_opmode_blocking);
            [~, Lelbow] = sim.simxGetObjectHandle(clientID,'Lelbow',sim.simx_opmode_blocking);
            [~, Loriento] = sim.simxGetObjectHandle(clientID,'Revolute_joint',sim.simx_opmode_blocking);
            [~, Lwrist] = sim.simxGetObjectHandle(clientID,'Lwrist',sim.simx_opmode_blocking);
            [~, Lhand] = sim.simxGetObjectHandle(clientID,'Lhand',sim.simx_opmode_blocking);
            [~, Lgripper] = sim.simxGetObjectHandle(clientID,'Prismatic_joint',sim.simx_opmode_blocking);
            
            
            % Right Loader
            [~,Loader_Door_P]=sim.simxGetObjectHandle(clientID,'Loader_Door_P',sim.simx_opmode_blocking);
            [~,Loader_Lift_P]=sim.simxGetObjectHandle(clientID,'Loader_Lift_P',sim.simx_opmode_blocking);
            [~,Loader_Slide_P]=sim.simxGetObjectHandle(clientID,'Loader_Slide_P',sim.simx_opmode_blocking);
            
            % Right Sensors
            [~,Loader_B_S]=sim.simxGetObjectHandle(clientID,'Loader_B_S',sim.simx_opmode_blocking);
            [~,Loader_T_S]=sim.simxGetObjectHandle(clientID,'Loader_T_S',sim.simx_opmode_blocking);
            [~,Loader_Ramp_S]=sim.simxGetObjectHandle(clientID,'Loader_Ramp_S',sim.simx_opmode_blocking);
            [~, Right_Handle] = sim.simxGetObjectHandle(clientID,'Right_Handle',sim.simx_opmode_blocking);
            
            % Left Loader
            [~,Loader_Door_PL]=sim.simxGetObjectHandle(clientID,'Loader_Door_P#0',sim.simx_opmode_blocking);
            [~,Loader_Lift_PL]=sim.simxGetObjectHandle(clientID,'Loader_Lift_P#0',sim.simx_opmode_blocking);
            [~,Loader_Slide_PL]=sim.simxGetObjectHandle(clientID,'Loader_Slide_P#0',sim.simx_opmode_blocking);
            
            
            % Left Sensors
            [~,Loader_B_SL]=sim.simxGetObjectHandle(clientID,'Loader_B_S#0',sim.simx_opmode_blocking);
            [~,Loader_T_SL]=sim.simxGetObjectHandle(clientID,'Loader_T_S#0',sim.simx_opmode_blocking);
            [~,Loader_Ramp_SL]=sim.simxGetObjectHandle(clientID,'Loader_Ramp_S#0',sim.simx_opmode_blocking);
            [~, Left_Handle] = sim.simxGetObjectHandle(clientID,'Left_Handle',sim.simx_opmode_blocking);
            
            
            % Setting up the color check
            [~, Sorter] = sim.simxGetObjectHandle(clientID,'Sort_Joint',sim.simx_opmode_blocking);
            [~,Scammer]=sim.simxGetObjectHandle(clientID,'Color_Check',sim.simx_opmode_blocking);

            [~, Funnel_LockT] = sim.simxGetObjectHandle(clientID,'Funnel_Lock0',sim.simx_opmode_blocking);
            [~, Funnel_LockB] = sim.simxGetObjectHandle(clientID,'Funnel_Lock',sim.simx_opmode_blocking);
            [~, Chip_Color_Check] = sim.simxGetObjectHandle(clientID,'Chip_Color_Check',sim.simx_opmode_blocking);
            
            % set up Rack postions
            [~,Rack_OP]=sim.simxGetObjectHandle(clientID,'Rack_OP',sim.simx_opmode_blocking);
            [~,Rack_OP0]=sim.simxGetObjectHandle(clientID,'Rack_OP0',sim.simx_opmode_blocking);
            [~,Rack_OP1]=sim.simxGetObjectHandle(clientID,'Rack_OP1',sim.simx_opmode_blocking);
            [~,Rack_OP2]=sim.simxGetObjectHandle(clientID,'Rack_OP2',sim.simx_opmode_blocking);
            [~,Rack_OP3]=sim.simxGetObjectHandle(clientID,'Rack_OP3',sim.simx_opmode_blocking);
            [~,Rack_OP4]=sim.simxGetObjectHandle(clientID,'Rack_OP4',sim.simx_opmode_blocking);
            [~,Rack_OP5]=sim.simxGetObjectHandle(clientID,'Rack_OP5',sim.simx_opmode_blocking);
            
            
            %reset pushers
            [~, Push1] = sim.simxGetObjectHandle(clientID,'Prismatic_joint1',sim.simx_opmode_blocking);
            [~, Push2] = sim.simxGetObjectHandle(clientID,'Prismatic_joint2',sim.simx_opmode_blocking);
            [~, Push3] = sim.simxGetObjectHandle(clientID,'Prismatic_joint3',sim.simx_opmode_blocking);
            [~, Push4] = sim.simxGetObjectHandle(clientID,'Prismatic_joint4',sim.simx_opmode_blocking);
            [~, Push5] = sim.simxGetObjectHandle(clientID,'Prismatic_joint5',sim.simx_opmode_blocking);
            [~, Push6] = sim.simxGetObjectHandle(clientID,'Prismatic_joint6',sim.simx_opmode_blocking);
            [~, Push7] = sim.simxGetObjectHandle(clientID,'Prismatic_joint7',sim.simx_opmode_blocking);
            
            [~, Reset_Setup] = sim.simxGetObjectHandle(clientID,'Reset_Setup',sim.simx_opmode_blocking);


            % Set up Loader initial vars
            LiftV = 20;
            DoorV = 5;
            SlideV = 12;
            LoadedR = false;
            LoadedL =false;
            sim.simxSetJointTargetVelocity(clientID,Loader_Door_P,-DoorV,sim.simx_opmode_blocking); %Open
            sim.simxSetJointTargetVelocity(clientID,Loader_Slide_P,SlideV,sim.simx_opmode_blocking); %Closed
            sim.simxSetJointTargetVelocity(clientID,Loader_Lift_P,-LiftV,sim.simx_opmode_blocking); %Down
            
            [~,RR,~,~,~]=sim.simxReadProximitySensor(clientID,Loader_Ramp_S,sim.simx_opmode_streaming);
            [~,BR,~,~,~]=sim.simxReadProximitySensor(clientID,Loader_B_S,sim.simx_opmode_streaming);
            [~,TR,~,~,~]=sim.simxReadProximitySensor(clientID,Loader_T_S,sim.simx_opmode_streaming);
            [~,HR,~,~,~]=sim.simxReadProximitySensor(clientID,Right_Handle,sim.simx_opmode_streaming);
            
            [~,RL,~,~,~]=sim.simxReadProximitySensor(clientID,Loader_Ramp_SL,sim.simx_opmode_streaming);
            [~,BL,~,~,~]=sim.simxReadProximitySensor(clientID,Loader_B_SL,sim.simx_opmode_streaming);
            [~,TL,~,~,~]=sim.simxReadProximitySensor(clientID,Loader_T_SL,sim.simx_opmode_streaming);
            [~,HL,~,~,~]=sim.simxReadProximitySensor(clientID,Left_Handle,sim.simx_opmode_streaming);
            
            Reset_Arm(app,1);
            Reset_Arm(app,2);
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            
            % Make Led's blank
            global Led_name board sim clientID Rack_OP Rack_OP0 ...
                Rack_OP1 Rack_OP2 Rack_OP3 Rack_OP4 Rack_OP5 Push1 ...
                Push2 Push3 Push4 Push5 Push6 Push7 Reset_Setup
            
            Led_name = {};
            board = zeros(6,7);
            
            for i = 1:42
                Led_name{i} = strcat('Lamp_',num2str(i));
            end
            for j=1:42
                
                app.(Led_name{j}).Color = [0.8 0.8 0.8];   %Turn Grey
            end
            
            % Randomly select 1,2 and Light the player LED
            
            r =rand(1)     % Guessing whose turn is it
            if r<=0.5
                app.player_1.Color = [1 0 0];    %light Red
                app.player_2.Color = [0.8 0.8 0.8];   %Turn Grey
            else
                app.player_1.Color = [1 1 0];    %light Yellow
                app.player_2.Color = [0.8 0.8 0.8];   %Turn Grey
                
            end
            %open loading box
            h = figure('units','pixels','position',[500 500 200 50],'windowstyle','modal');
            uicontrol('style','text','string','Loading...','units','pixels','position',[75 10 50 30]);
            pause(0.1)
            %run reset
            sim.simxSetJointTargetPosition(clientID, Rack_OP, 0.36, sim.simx_opmode_blocking);
            sim.simxSetJointTargetPosition(clientID, Rack_OP0, 0.36, sim.simx_opmode_blocking);
            sim.simxSetJointTargetPosition(clientID, Rack_OP1, 0.36, sim.simx_opmode_blocking);
            sim.simxSetJointTargetPosition(clientID, Rack_OP2, 0.36, sim.simx_opmode_blocking);
            sim.simxSetJointTargetPosition(clientID, Rack_OP3, 0.36, sim.simx_opmode_blocking);
            sim.simxSetJointTargetPosition(clientID, Rack_OP4, 0.36, sim.simx_opmode_blocking);
            sim.simxSetJointTargetPosition(clientID, Rack_OP5, 0.36, sim.simx_opmode_blocking);
            
            sim.simxSetJointTargetPosition(clientID, Reset_Setup,0, sim.simx_opmode_blocking);
            pause(0.1)
            sim.simxSetJointTargetVelocity(clientID, Push1, -0.3, sim.simx_opmode_blocking);
            sim.simxSetJointTargetVelocity(clientID, Push2, -0.3, sim.simx_opmode_blocking);
            sim.simxSetJointTargetVelocity(clientID, Push3, -0.3, sim.simx_opmode_blocking);
            sim.simxSetJointTargetVelocity(clientID, Push4, -0.3, sim.simx_opmode_blocking);
            sim.simxSetJointTargetVelocity(clientID, Push5, -0.3, sim.simx_opmode_blocking);
            sim.simxSetJointTargetVelocity(clientID, Push6, -0.3, sim.simx_opmode_blocking);
            sim.simxSetJointTargetVelocity(clientID, Push7, -0.3, sim.simx_opmode_blocking);
            pause(5)
            sim.simxSetJointTargetVelocity(clientID, Push1, 0.3, sim.simx_opmode_blocking);
            sim.simxSetJointTargetVelocity(clientID, Push2, 0.3, sim.simx_opmode_blocking);
            sim.simxSetJointTargetVelocity(clientID, Push3, 0.3, sim.simx_opmode_blocking);
            sim.simxSetJointTargetVelocity(clientID, Push4, 0.3, sim.simx_opmode_blocking);
            sim.simxSetJointTargetVelocity(clientID, Push5, 0.3, sim.simx_opmode_blocking);
            sim.simxSetJointTargetVelocity(clientID, Push6, 0.3, sim.simx_opmode_blocking);
            sim.simxSetJointTargetVelocity(clientID, Push7, 0.3, sim.simx_opmode_blocking);
            pause(5)
            sim.simxSetJointTargetPosition(clientID, Rack_OP, 0.5, sim.simx_opmode_blocking);
            sim.simxSetJointTargetPosition(clientID, Rack_OP0, 0.5, sim.simx_opmode_blocking);
            sim.simxSetJointTargetPosition(clientID, Rack_OP1, 0.5, sim.simx_opmode_blocking);
            sim.simxSetJointTargetPosition(clientID, Rack_OP2, 0.5, sim.simx_opmode_blocking);
            sim.simxSetJointTargetPosition(clientID, Rack_OP3, 0.5, sim.simx_opmode_blocking);
            sim.simxSetJointTargetPosition(clientID, Rack_OP4, 0.5, sim.simx_opmode_blocking);
            sim.simxSetJointTargetPosition(clientID, Rack_OP5, 0.5, sim.simx_opmode_blocking);
            pause(0.1)
            sim.simxSetJointTargetPosition(clientID, Reset_Setup,170*pi/180, sim.simx_opmode_blocking);
            pause(1)
            
            close(h)
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            %Exit matlab
            global sim
            sim.simxFinish(-1);  %end connection
            %exit
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            Connect_4(app, 1)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            Connect_4(app, 2)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            Connect_4(app, 3)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            Connect_4(app, 4)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            Connect_4(app, 5)
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            Connect_4(app, 6)
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            Connect_4(app, 7)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Lamp_1 = uilamp(app.UIFigure);
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.player1LampLabel = uilabel(app.UIFigure);
            app.player1LampLabel.Text = 'player 1';
            app.player_1 = uilamp(app.UIFigure);
            app.player2LampLabel = uilabel(app.UIFigure);
            app.player2LampLabel.Text = 'player 2';
            app.player_2 = uilamp(app.UIFigure);
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.QUITButton = uibutton(app.UIFigure, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            app.Lamp_9 = uilamp(app.UIFigure);
            app.Lamp_10 = uilamp(app.UIFigure);
            app.Lamp_11 = uilamp(app.UIFigure);
            app.Lamp_12 = uilamp(app.UIFigure);
            app.Lamp_13 = uilamp(app.UIFigure);
            app.Lamp_14 = uilamp(app.UIFigure);
            app.Lamp_15 = uilamp(app.UIFigure);
            app.Lamp_16 = uilamp(app.UIFigure);
            app.Lamp_17 = uilamp(app.UIFigure);
            app.Lamp_18 = uilamp(app.UIFigure);
            app.Lamp_19 = uilamp(app.UIFigure);
            app.Lamp_20 = uilamp(app.UIFigure);
            app.Lamp_21 = uilamp(app.UIFigure);
            app.Lamp_22 = uilamp(app.UIFigure);
            app.Lamp_23 = uilamp(app.UIFigure);
            app.Lamp_24 = uilamp(app.UIFigure);
            app.Lamp_25 = uilamp(app.UIFigure);
            app.Lamp_26 = uilamp(app.UIFigure);
            app.Lamp_27 = uilamp(app.UIFigure);
            app.Lamp_28 = uilamp(app.UIFigure);
            app.Lamp_29 = uilamp(app.UIFigure);
            app.Lamp_30 = uilamp(app.UIFigure);
            app.Lamp_31 = uilamp(app.UIFigure);
            app.Lamp_32 = uilamp(app.UIFigure);
            app.Lamp_33 = uilamp(app.UIFigure);
            app.Lamp_34 = uilamp(app.UIFigure);
            app.Lamp_35 = uilamp(app.UIFigure);
            app.Lamp_36 = uilamp(app.UIFigure);
            app.Lamp_37 = uilamp(app.UIFigure);
            app.Lamp_38 = uilamp(app.UIFigure);
            app.Lamp_39 = uilamp(app.UIFigure);
            app.Lamp_40 = uilamp(app.UIFigure);
            app.Lamp_41 = uilamp(app.UIFigure);
            app.Lamp_42 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end